classdef PG_CEP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ANALYSISTab                    matlab.ui.container.Tab
        STATIONDropDown                matlab.ui.control.DropDown
        STATIONDropDownLabel           matlab.ui.control.Label
        PlantUseFactorEditField        matlab.ui.control.NumericEditField
        PlantUseFactorLabel            matlab.ui.control.Label
        LOADButton                     matlab.ui.control.Button
        VariableCostRsEditField        matlab.ui.control.NumericEditField
        VariableCostRsEditFieldLabel   matlab.ui.control.Label
        IntrerestandDepreciationEditField  matlab.ui.control.NumericEditField
        IntrerestandDepreciationLabel  matlab.ui.control.Label
        FixedCostRsEditField           matlab.ui.control.NumericEditField
        FixedCostRsEditFieldLabel      matlab.ui.control.Label
        MaintenancecostRsEditField     matlab.ui.control.NumericEditField
        MaintenancecostRsEditFieldLabel  matlab.ui.control.Label
        OperatingCostRsKWhEditField    matlab.ui.control.NumericEditField
        OperatingCostRsKWhEditFieldLabel  matlab.ui.control.Label
        CapitalCostRsKWEditField       matlab.ui.control.NumericEditField
        CapitalCostRsKWEditFieldLabel  matlab.ui.control.Label
        PlantCapacityFactorEditField   matlab.ui.control.NumericEditField
        PlantCapacityFactorEditFieldLabel  matlab.ui.control.Label
        PlantCapacityMWEditField       matlab.ui.control.NumericEditField
        PlantCapacityMWEditFieldLabel  matlab.ui.control.Label
        MaximumDemandMWEditField       matlab.ui.control.NumericEditField
        MaximumDemandMWEditFieldLabel  matlab.ui.control.Label
        AnnualEnergyKWhEditField       matlab.ui.control.NumericEditField
        AnnualEnergyKWhEditFieldLabel  matlab.ui.control.Label
        LoadFactorEditField            matlab.ui.control.NumericEditField
        LoadFactorEditFieldLabel       matlab.ui.control.Label
        AverageLoadKWEditField         matlab.ui.control.NumericEditField
        AverageLoadKWEditFieldLabel    matlab.ui.control.Label
        EnergyPerDayKWhEditField       matlab.ui.control.NumericEditField
        EnergyPerDayKWhEditFieldLabel  matlab.ui.control.Label
        ECONOMICALANALYSISLabel        matlab.ui.control.Label
        TECHNICALANALYSISLabel         matlab.ui.control.Label
        CALCULATEButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t
        x
        y
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            app.t=readtable("C:\Users\sajja\Documents\Sajjad's Archives\last sem\PG_CEP.xlsx");
            app.UITable.Data=app.t;

            app.x = table2array(app.t(:,1));
            app.y = table2array(app.t(:,2));
            plot(app.UIAxes,app.x,app.y);
        end

        % Value changed function: STATIONDropDown
        function STATIONDropDownValueChanged(app, event)

        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            value = app.STATIONDropDown.Value;

            energy = sum(app.y)*1000;
            app.EnergyPerDayKWhEditField.Value = energy;
            
            avg = (sum(app.y)*1000)/24;
            app.AverageLoadKWEditField.Value = avg;
            
            maximum = max(app.y);
            app.MaximumDemandMWEditField.Value = maximum;
            
            load = avg/(maximum*1000);
            app.LoadFactorEditField.Value = load;
            
            annual = avg*8760;
            app.AnnualEnergyKWhEditField.Value = annual;

            switch value
                case 'Diesel'
                    plant_cap = 2500;
                    capit_cost = 7000;
                    main_cost = 23000000;
                    op_cost = 0.5216;
                    
                case 'Nuclear'
                    plant_cap = 2200;
                    capit_cost = 63000;
                    main_cost = 0;
                    op_cost = 0.0163;

            end
            
            app.PlantCapacityMWEditField.Value = plant_cap;
            
            plant_cap_factor = (avg/(plant_cap*1000));
            app.PlantCapacityFactorEditField.Value = plant_cap_factor;
            
            plant_use_factor = (energy/(40900*1000));
            app.PlantUseFactorEditField.Value = plant_use_factor;
            
            app.CapitalCostRsKWEditField.Value = capit_cost;
            
            int_dep = 10;
            app.IntrerestandDepreciationEditField.Value = int_dep;
            
            app.MaintenancecostRsEditField.Value = main_cost;
            
            fixed_cost = (capit_cost*int_dep*plant_cap*1000)/100 + main_cost;
            app.FixedCostRsEditField.Value = fixed_cost;
            
            app.OperatingCostRsKWhEditField.Value = op_cost;           
            
            var_cost = (op_cost*annual)+main_cost;
            app.VariableCostRsEditField.Value = var_cost;
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ANALYSISTab = uitab(app.TabGroup);
            app.ANALYSISTab.Title = 'ANALYSIS';
            app.UIAxes = uiaxes(app.ANALYSISTab);
            title(app.UIAxes, 'LOAD CURVE')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'LOAD')
            app.UITable = uitable(app.ANALYSISTab);
            app.UITable.ColumnName = {'TIME'; 'LOAD'};
            app.UITable.RowName = {};
            app.CALCULATEButton = uibutton(app.ANALYSISTab, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.TECHNICALANALYSISLabel = uilabel(app.ANALYSISTab);
            app.TECHNICALANALYSISLabel.Text = 'TECHNICAL ANALYSIS';
            app.ECONOMICALANALYSISLabel = uilabel(app.ANALYSISTab);
            app.ECONOMICALANALYSISLabel.Text = 'ECONOMICAL ANALYSIS';
            app.EnergyPerDayKWhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.EnergyPerDayKWhEditFieldLabel.Text = 'Energy Per Day (KWh)';
            app.EnergyPerDayKWhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.AverageLoadKWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.AverageLoadKWEditFieldLabel.Text = 'Average Load (KW)';
            app.AverageLoadKWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.LoadFactorEditFieldLabel = uilabel(app.ANALYSISTab);
            app.LoadFactorEditFieldLabel.Text = 'Load Factor';
            app.LoadFactorEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.AnnualEnergyKWhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.AnnualEnergyKWhEditFieldLabel.Text = 'Annual Energy (KWh)';
            app.AnnualEnergyKWhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.MaximumDemandMWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.MaximumDemandMWEditFieldLabel.Text = 'Maximum Demand (MW)';
            app.MaximumDemandMWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.PlantCapacityMWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.PlantCapacityMWEditFieldLabel.Text = 'Plant Capacity (MW)';
            app.PlantCapacityMWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.PlantCapacityFactorEditFieldLabel = uilabel(app.ANALYSISTab);
            app.PlantCapacityFactorEditFieldLabel.Text = 'Plant Capacity Factor';
            app.PlantCapacityFactorEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.CapitalCostRsKWEditFieldLabel = uilabel(app.ANALYSISTab);
            app.CapitalCostRsKWEditFieldLabel.Text = 'Capital Cost (Rs/KW)';
            app.CapitalCostRsKWEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.OperatingCostRsKWhEditFieldLabel = uilabel(app.ANALYSISTab);
            app.OperatingCostRsKWhEditFieldLabel.Text = 'Operating Cost (Rs/KWh)';
            app.OperatingCostRsKWhEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.MaintenancecostRsEditFieldLabel = uilabel(app.ANALYSISTab);
            app.MaintenancecostRsEditFieldLabel.Text = 'Maintenance cost (Rs)';
            app.MaintenancecostRsEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.FixedCostRsEditFieldLabel = uilabel(app.ANALYSISTab);
            app.FixedCostRsEditFieldLabel.Text = 'Fixed Cost (Rs)';
            app.FixedCostRsEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.IntrerestandDepreciationLabel = uilabel(app.ANALYSISTab);
            app.IntrerestandDepreciationLabel.Text = 'Intrerest and Depreciation (%)';
            app.IntrerestandDepreciationEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.VariableCostRsEditFieldLabel = uilabel(app.ANALYSISTab);
            app.VariableCostRsEditFieldLabel.Text = 'Variable Cost (Rs)';
            app.VariableCostRsEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.LOADButton = uibutton(app.ANALYSISTab, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.PlantUseFactorLabel = uilabel(app.ANALYSISTab);
            app.PlantUseFactorLabel.Text = 'Plant Use Factor';
            app.PlantUseFactorEditField = uieditfield(app.ANALYSISTab, 'numeric');
            app.STATIONDropDownLabel = uilabel(app.ANALYSISTab);
            app.STATIONDropDownLabel.Text = 'STATION';
            app.STATIONDropDown = uidropdown(app.ANALYSISTab);
            app.STATIONDropDown.Items = {'Diesel', 'Nuclear'};
            app.STATIONDropDown.ValueChangedFcn = createCallbackFcn(app, @STATIONDropDownValueChanged, true);
            app.STATIONDropDown.Value = 'Diesel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PG_CEP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end